classdef ImageWatermarkingApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        BrowseOriginalImageButton   matlab.ui.control.Button
        BrowseWatermarkImageButton  matlab.ui.control.Button
        SaveImageButton             matlab.ui.control.Button
        CloseButton                 matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
    end

    methods (Access = private)

        % Button pushed function: BrowseOriginalImageButton
        function openOrigianlImage(app, event)
            [filename pathname] = uigetfile('*.jpg;*.png;*.bmp', 'Pick any file');
            if isequal(filename,0)
                disp('User selected Cancel');
            else
                p = imread([pathname, filename]);
                p = imresize(p, [400, 400]);
            
                %imshow(p)
                axes(app.UIAxes);
                imshow(myImage);
            end
            
        end

        % Button pushed function: BrowseWatermarkImageButton
        function openWatermarkImage(app, event)
            
        end

        % Button pushed function: SaveImageButton
        function saveImage(app, event)
            
        end

        % Button pushed function: CloseButton
        function closeApp(app, event)
            delete(app.UIFigure)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.BrowseOriginalImageButton = uibutton(app.UIFigure, 'push');
            app.BrowseOriginalImageButton.ButtonPushedFcn = createCallbackFcn(app, @openOrigianlImage, true);
            app.BrowseOriginalImageButton.Text = 'Browse Original Image';
            app.BrowseWatermarkImageButton = uibutton(app.UIFigure, 'push');
            app.BrowseWatermarkImageButton.ButtonPushedFcn = createCallbackFcn(app, @openWatermarkImage, true);
            app.BrowseWatermarkImageButton.Text = 'Browse Watermark Image';
            app.SaveImageButton = uibutton(app.UIFigure, 'push');
            app.SaveImageButton.ButtonPushedFcn = createCallbackFcn(app, @saveImage, true);
            app.SaveImageButton.Text = 'Save Image';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @closeApp, true);
            app.CloseButton.Text = 'Close';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Digital Image Watermarking')
        end
    end

    methods (Access = public)

        % Construct app
        function app = ImageWatermarkingApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end